classdef Ristretto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure             matlab.ui.Figure
        MainFigureGridLayout   matlab.ui.container.GridLayout
        TabGroup               matlab.ui.container.TabGroup
        RawFilesTab            matlab.ui.container.Tab
        RawFilesTabGridLayout  matlab.ui.container.GridLayout
        RawFilesTable          matlab.ui.control.Table
        FolderGridLayout       matlab.ui.container.GridLayout
        EditFieldLabel         matlab.ui.control.Label
        FolderEditField        matlab.ui.control.EditField
        FolderButton           matlab.ui.control.Button
        LoadedFilesTab         matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CoffeeCodeRootFolder % Description
        FData
        FDataVersion
        
    end
    
    
    methods (Access = private)
        
        % Update Raw Files Tab
        function updateRawFilesTable(app)
            
            % list raw_files in search path
            rawDataFolder = app.FolderEditField.Value;
            rawDataFilesList = CFF_list_raw_files_in_dir(rawDataFolder);
            
            % check which are already converted
            [idx_converted,flag_outdated_fdata] = CFF_are_raw_files_converted(rawDataFilesList);
            
            % check which are currently loaded
            idx_loaded = CFF_are_raw_files_loaded(rawDataFilesList, app.FData);
            
            % prepare array
            n_rawfiles = numel(rawDataFilesList);
            [disp_folder, filename, ext] = CFF_fileparts_as_cell(CFF_onerawfileonly(rawDataFilesList));
            disp_files = strcat(filename, ext);
            for ii = 1:n_rawfiles
                if iscell(rawDataFilesList{ii})
                    % paired file
                    disp_files{ii} = strcat(disp_files{ii}, ' (paired)');
                end
            end
            new_entry = [disp_files, disp_folder];
            
            % update file_tab_comp
            
            app.RawFilesTable.Data = new_entry;
            %file_tab_comp.files = rawDataFilesList;
            %file_tab_comp.idx_converted = idx_converted;
            
            removeStyle(app.RawFilesTable);
            if ~isempty(new_entry)
                % set files colors depending on status
                app.RawFilesTable.Visible = 'off';
                idx2subs = @(idx) cat(2,find([idx;0]),ones(numel(find(idx)),1)); % function to turn idx to subscript indices for addStyle
                styleNotConverted = uistyle('FontColor',[0.5 0.5 0.5]); % gray
                addStyle(app.RawFilesTable,styleNotConverted,'cell',idx2subs(~idx_converted));
                styleConvertedNotLoaded = uistyle('FontWeight','bold','FontColor','k');
                addStyle(app.RawFilesTable,styleConvertedNotLoaded,'cell',idx2subs(idx_converted&~idx_loaded));
                styleLoaded = uistyle('FontWeight','bold','FontColor',[0.4660 0.6740 0.1880]); % green
                addStyle(app.RawFilesTable,styleLoaded,'cell',idx2subs(idx_converted&idx_loaded));
                app.RawFilesTable.Visible = 'on';
            end
            
            app.RawFilesTable.Visible = 'on';
            
            % throw warning for outdated version
            if flag_outdated_fdata
                warning('One or several files in this folder have been previously converted using an outdated version of Espresso. They will require reconversion and thus show as NOT converted.');
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Resize figure according to monitor's dimensions
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.MainFigure.Position = [left bottom width height];
            
            % Add Coffee code to path
            app.CoffeeCodeRootFolder = 'C:\Users\Schimel_Alexandre\Code\MATLAB\Espresso\toolboxes\coffee';
            if isfolder(app.CoffeeCodeRootFolder)
                % Add to path
                addpath(genpath(app.CoffeeCodeRootFolder));
                app.FDataVersion = CFF_get_current_fData_version();
            else
                % To-do XXX1: Throw an alert to find manually a suitable
                % coffee folder
            end
            
            % Set default folder location
            app.FolderEditField.Value = cd;
            
            % Initialize Raw Files table
            app.updateRawFilesTable();
            
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            % Open folder selection dialog box
            currentFolder = app.FolderEditField.Value;
            newFolder = uigetdir(currentFolder,'Select folder of raw data files');
            if newFolder ~= 0
                % Update value of the FolderEditField
                app.FolderEditField.Value = newFolder;
                % Update Raw Files Table contents
                app.updateRawFilesTable();
            end
        end

        % Value changed function: FolderEditField
        function FolderEditFieldValueChanged(app, event)
            % Check if value is an existing folder
            requestedFolder = app.FolderEditField.Value;
            if ~isfolder(requestedFolder)
                % Error dialog box
                uialert(app.MainFigure,'Folder not found','Invalid Folder','CloseFcn','uiresume(gcbf)');
                uiwait(app.MainFigure)
                % Reset previous field value
                app.FolderEditField.Value = event.PreviousValue;
            else
                % Update Raw Files Table contents
                app.updateRawFilesTable();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'Ristretto';
            app.MainFigureGridLayout = uigridlayout(app.MainFigure);
            app.TabGroup = uitabgroup(app.MainFigureGridLayout);
            app.RawFilesTab = uitab(app.TabGroup);
            app.RawFilesTab.Title = 'Raw files';
            app.RawFilesTabGridLayout = uigridlayout(app.RawFilesTab);
            app.RawFilesTable = uitable(app.RawFilesTabGridLayout);
            app.RawFilesTable.ColumnName = {'File'; 'Folder'};
            app.RawFilesTable.RowName = {};
            app.RawFilesTable.ColumnSortable = true;
            app.RawFilesTable.ColumnEditable = [false false];
            app.FolderGridLayout = uigridlayout(app.RawFilesTabGridLayout);
            app.EditFieldLabel = uilabel(app.FolderGridLayout);
            app.EditFieldLabel.Text = 'Edit Field';
            app.FolderEditField = uieditfield(app.FolderGridLayout, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
            app.FolderButton = uibutton(app.FolderGridLayout, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Icon = 'foldericon.gif';
            app.FolderButton.Text = '';
            app.LoadedFilesTab = uitab(app.TabGroup);
            app.LoadedFilesTab.Title = 'Loaded files';
            app.UIAxes = uiaxes(app.MainFigureGridLayout);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ristretto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end